classdef AproxCuadratica < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        AproximacinCuadrticaLabel      matlab.ui.control.Label
        IngresesufuncinEditFieldLabel  matlab.ui.control.Label
        IngresesufuncinEditField       matlab.ui.control.EditField
        EvaluarenEditFieldLabel        matlab.ui.control.Label
        EvaluarenEditField             matlab.ui.control.EditField
        EvaluarenEditField_2           matlab.ui.control.EditField
        CalcularButton                 matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        res                            matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            equ = str2sym(app.IngresesufuncinEditField.Value);
            x0 = str2sym(app.EvaluarenEditField.Value);
            y0 = str2sym(app.EvaluarenEditField_2.Value);

            syms x y;
       
            fx = char(diff(equ, x));
            fy = char(diff(equ, y));
            fxx = char(diff(fx, x));
            fxy = char(diff(fx, y));
            fyy = char(diff(fy, y));
            
            f = str2func(['@(x,y)' char(equ)]);
            ffx = str2func(['@(x,y)' fx]);
            ffy = str2func(['@(x,y)' fy]);
            ffxx = str2func(['@(x,y)' fxx]);
            ffxy = str2func(['@(x,y)' fxy]);
            ffyy = str2func(['@(x,y)' fyy]);
            
            fnc_cuadratica = str2func('@(f, fx, fy, fxx, fyy, fxy, x0, y0, x, y) f+fx*(x-x0) +fy*(y-y0) + 0.5*fxx*(x-x0)^2 + fxy*(x-x0)*(y-y0) + 0.5*fyy*(y-y0)^2');
            
            f_eval  = f(x0, y0);
            fx_eval = ffx(x0, y0);
            fy_eval = ffy(x0, y0);
            fxx_eval = ffxx(x0, y0);
            fxy_eval = ffxy(x0, y0);
            fyy_eval = ffyy(x0, y0);
            
            resultado = fnc_cuadratica(f_eval, fx_eval, fy_eval, fxx_eval, fyy_eval, fxy_eval, x0, y0, x, y);
            
            app.res.Text = ['Resultado: ' char(simplify(resultado))];

           app.UITable.Data = {char(equ), fx, fy, fxx, fxy, fyy; char(f_eval), char(fx_eval), char(fy_eval), char(fxx_eval), char(fxy_eval), char(fyy_eval)};
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.AproximacinCuadrticaLabel = uilabel(app.UIFigure);
            app.AproximacinCuadrticaLabel.Text = 'Aproximación Cuadrática';
            app.IngresesufuncinEditFieldLabel = uilabel(app.UIFigure);
            app.IngresesufuncinEditFieldLabel.Text = 'Ingrese su función';
            app.IngresesufuncinEditField = uieditfield(app.UIFigure, 'text');
            app.IngresesufuncinEditField.Value = 'exp(x/2)*sin(y)';
            app.EvaluarenEditFieldLabel = uilabel(app.UIFigure);
            app.EvaluarenEditFieldLabel.Text = 'Evaluar en';
            app.EvaluarenEditField = uieditfield(app.UIFigure, 'text');
            app.EvaluarenEditField.Value = '0';
            app.EvaluarenEditField_2 = uieditfield(app.UIFigure, 'text');
            app.EvaluarenEditField_2.Value = 'pi/2';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'F'; 'Fx'; 'Fy'; 'Fxx'; 'Fxy'; 'Fyy'};
            app.UITable.RowName = {};
            app.res = uilabel(app.UIFigure);
            app.res.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AproxCuadratica

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end