classdef Euler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        AproximacinSucesivaEulerLabel  matlab.ui.control.Label
        X0EditFieldLabel               matlab.ui.control.Label
        X0EditField                    matlab.ui.control.NumericEditField
        Y0EditFieldLabel               matlab.ui.control.Label
        Y0EditField                    matlab.ui.control.NumericEditField
        IncrementoEditFieldLabel       matlab.ui.control.Label
        IncrementoEditField            matlab.ui.control.NumericEditField
        XfEditFieldLabel               matlab.ui.control.Label
        XfEditField                    matlab.ui.control.NumericEditField
        FuncinEditFieldLabel           matlab.ui.control.Label
        FuncinEditField                matlab.ui.control.EditField
        CalcularButton                 matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
    end

    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            eq = app.FuncinEditField.Value;
            x0 = app.X0EditField.Value;
            y0 = app.Y0EditField.Value;
            h  = app.IncrementoEditField.Value;
            xf = app.XfEditField.Value;
            
            f = str2func(['@(x,y)' eq]);
            
            values = [x0, y0, f(x0, y0)];
            
            %values = [values; 2, 3, 5];
%             disp(values(1, 1:3));
%             disp(values(2, 1:3));
            i = 1;
            for j = x0+h:h:xf
                yAnt = values(i, 2);
                fxyAnt = values(i, 3);
                yi = yAnt+h*fxyAnt;
                values = [values;j, yi, f(j, yi)];
                i = i+1;
            end
            %disp(values);
            
            app.UITable.Data = values;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.AproximacinSucesivaEulerLabel = uilabel(app.UIFigure);
            app.AproximacinSucesivaEulerLabel.Text = 'Aproximación Sucesiva Euler';
            app.X0EditFieldLabel = uilabel(app.UIFigure);
            app.X0EditFieldLabel.Text = 'X0';
            app.X0EditField = uieditfield(app.UIFigure, 'numeric');
            app.Y0EditFieldLabel = uilabel(app.UIFigure);
            app.Y0EditFieldLabel.Text = 'Y0';
            app.Y0EditField = uieditfield(app.UIFigure, 'numeric');
            app.Y0EditField.ValueDisplayFormat = '%.3f';
            app.Y0EditField.Value = 2;
            app.IncrementoEditFieldLabel = uilabel(app.UIFigure);
            app.IncrementoEditFieldLabel.Text = 'Incremento';
            app.IncrementoEditField = uieditfield(app.UIFigure, 'numeric');
            app.IncrementoEditField.Value = 0.1;
            app.XfEditFieldLabel = uilabel(app.UIFigure);
            app.XfEditFieldLabel.Text = 'Xf';
            app.XfEditField = uieditfield(app.UIFigure, 'numeric');
            app.XfEditField.Value = 1;
            app.FuncinEditFieldLabel = uilabel(app.UIFigure);
            app.FuncinEditFieldLabel.Text = 'Función';
            app.FuncinEditField = uieditfield(app.UIFigure, 'text');
            app.FuncinEditField.Value = 'x-y+2';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'X'; 'Y'; 'f(x,y)'};
            app.UITable.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Euler

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end