classdef FalsaPos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        MtododeFalsaPosicinLabel   matlab.ui.control.Label
        EscribasuecuacinPanel      matlab.ui.container.Panel
        EscribasuecuacinEditField  matlab.ui.control.EditField
        EditField                  matlab.ui.control.NumericEditField
        hastaLabel                 matlab.ui.control.Label
        EditField_2                matlab.ui.control.NumericEditField
        desdeLabel                 matlab.ui.control.Label
        CalcularButton             matlab.ui.control.Button
        Label                      matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            Encontrado = 0;
            i          = 1;
            eq         = app.EscribasuecuacinEditField.Value;
            liminf     = app.EditField_2.Value;
            limsup     = app.EditField.Value;
            % Verificar si se introdujo una función
            if isempty(eq)
               app.Label.Text = '¡Debes de escribir una ecuación!';
               return;
            end
            % Verificar que el rango no este vació
            if isempty(limsup) | isempty(liminf)
               app.Label.Text = 'Rellene ambos limites para evaluar.';
               return;
            end
            % Verificar si los limites son iguales, para evitar crasheo.
            if limsup == liminf
                app.Label.Text = 'Los limites no pueden ser iguales.';
                return;
            end
            % A sería el limite inferior
            A = liminf;
            % B Sería el limite superior
            B = limsup;
            % Convertimos el texto a una expresión que pueda ser evaluada.
            f = str2func(['@(x)' eq]);
            while ~Encontrado
                %Evaluamos
                A_Eval = f(A);
                B_Eval = f(B);
                %Obtenemos el valor de Xr
                Xr = (A*B_Eval-B*A_Eval)/(B_Eval-A_Eval);
                disp([A, B, Xr]);
                %Evaluamos el Error si es la iteración 2 en adelante, si el error en porcentaje es <= 1% Cerramos
                if i >= 2
                    Err = abs((Xr-XrOld)/Xr)* 100;
                    if Err <= 0.01
                       Encontrado = 1; 
                       app.Label.Text = sprintf('Se encontro el valor de la raiz en %d con %f iteraciones', Xr, i);
                       return;
                    end

                end
                XR_Eval = f(Xr);
                disp(XR_Eval);
                %Multiplicamos la función evaluada en a por la función evaluada en Xr
                Res = A_Eval * XR_Eval;
                %¿El resultado es 0? Se encontró la raíz
                if Res == 0
                    app.Label.Text = sprintf('Se encontro el valor de la raiz en %d con %f iteraciones', Xr, i);
                    Encontrado = 1;
                    return;
                end
                XrOld = Xr;
                %¿El resultado es mayor a 0? Sustituir a por Xr y reintentar
                if Res > 0
                    A = Xr;
                else %¿El resultado es menor a 0? Sustituor b por Xr
                    B = Xr;
                end

                i = i + 1;
                
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.MtododeFalsaPosicinLabel = uilabel(app.UIFigure);
            app.MtododeFalsaPosicinLabel.Text = 'Método de Falsa Posición';
            app.EscribasuecuacinPanel = uipanel(app.UIFigure);
            app.EscribasuecuacinPanel.Title = 'Escriba su ecuación';
            app.EscribasuecuacinEditField = uieditfield(app.EscribasuecuacinPanel, 'text');
            app.EscribasuecuacinEditField.Value = '2*x*cos(x)-3';
            app.EditField = uieditfield(app.EscribasuecuacinPanel, 'numeric');
            app.EditField.Value = 2;
            app.hastaLabel = uilabel(app.EscribasuecuacinPanel);
            app.hastaLabel.Text = 'hasta';
            app.EditField_2 = uieditfield(app.EscribasuecuacinPanel, 'numeric');
            app.EditField_2.Value = -2;
            app.desdeLabel = uilabel(app.EscribasuecuacinPanel);
            app.desdeLabel.Text = 'desde';
            app.CalcularButton = uibutton(app.EscribasuecuacinPanel, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = FalsaPos

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end