classdef MinimosCuadrados < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        MtododemnimoscuadradosLabel  matlab.ui.control.Label
        Ecuacin1EditFieldLabel       matlab.ui.control.Label
        Ecuacin1EditField            matlab.ui.control.EditField
        Ecuacin2EditFieldLabel       matlab.ui.control.Label
        Ecuacin2EditField            matlab.ui.control.EditField
        Ecuacin3EditFieldLabel       matlab.ui.control.Label
        Ecuacin3EditField            matlab.ui.control.EditField
        CalcularButton               matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            eq1 = str2sym(app.Ecuacin1EditField.Value);
            eq2 = str2sym(app.Ecuacin2EditField.Value);
            eq3 = str2sym(app.Ecuacin3EditField.Value);

            [A,b] = equationsToMatrix([eq1 eq2 eq3]);
            
            At = transpose(A);
            
            AtA = At * A;
            Atb = At * b;
            
            X = rref([AtA Atb]);
            %Vector X^*
            VX = X(:,end);
            
            AVX = A * VX;

            AVXb = AVX - b;
            
            SumAVXb = sum(AVXb.^2);
            
            res = simplify(sqrt(SumAVXb));
            
            disp(A);
            disp(b);
            disp(At);
            disp(AtA);
            disp(Atb);
            disp(VX);
            disp(AVX);
            disp(AVXb.^2);
            disp(SumAVXb);
            disp(res);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.MtododemnimoscuadradosLabel = uilabel(app.UIFigure);
            app.MtododemnimoscuadradosLabel.Text = 'Método de mínimos cuadrados';
            app.Ecuacin1EditFieldLabel = uilabel(app.UIFigure);
            app.Ecuacin1EditFieldLabel.Text = 'Ecuación 1';
            app.Ecuacin1EditField = uieditfield(app.UIFigure, 'text');
            app.Ecuacin1EditField.Value = '2*x-y=2';
            app.Ecuacin2EditFieldLabel = uilabel(app.UIFigure);
            app.Ecuacin2EditFieldLabel.Text = 'Ecuación 2';
            app.Ecuacin2EditField = uieditfield(app.UIFigure, 'text');
            app.Ecuacin2EditField.Value = 'x+2*y=1';
            app.Ecuacin3EditFieldLabel = uilabel(app.UIFigure);
            app.Ecuacin3EditFieldLabel.Text = 'Ecuación 3';
            app.Ecuacin3EditField = uieditfield(app.UIFigure, 'text');
            app.Ecuacin3EditField.Value = 'x+y=4';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MinimosCuadrados

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end